classdef audioProcessingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        AudioRecorderSwitchLabel  matlab.ui.control.Label
        AudioRecorderSwitch       matlab.ui.control.Switch
        Lamp                      matlab.ui.control.Lamp
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes5                   matlab.ui.control.UIAxes
        FilterSwitchLabel         matlab.ui.control.Label
        FilterSwitch              matlab.ui.control.Switch
        Lamp_2                    matlab.ui.control.Lamp
        Switch                    matlab.ui.control.RockerSwitch
        OriginaudioLampLabel      matlab.ui.control.Label
        OriginaudioLamp           matlab.ui.control.Lamp
        AfteraudioLampLabel       matlab.ui.control.Label
        AfteraudioLamp            matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        recObj % 录音数据
        myRecording
        myRecording1
        h_rec
        h_rec1
        out
        out1
        x = (1:40000)/8000
        y = (-20000:19999)/2.5
        %N = 40000
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %理想低通的冲激响应hdn
            N = 40000;
            wc = 0.4*pi;
            n=(0:N-1);
            r = (N-1)/2;
            hdn = sin(wc*(n-r))/pi./(n-r);
            if rem(N,2)~=0
                hdn(r+1) = wc/pi;
            end
            %矩形窗
            w_rec=rectwin(N);
            app.h_rec=hdn.*w_rec';
            %对加窗后序列进行DFT
            app.h_rec1 = fft(app.h_rec,N);
        end

        % Value changed function: AudioRecorderSwitch
        function AudioRecorderSwitchValueChanged(app, event)
            if strcmp(app.AudioRecorderSwitch.Value, 'On')
                app.recObj = audiorecorder(8000,8,1);
                recordblocking(app.recObj, 5);
                app.Lamp.Color = [0 1 0] ;
            else
                app.myRecording = getaudiodata(app.recObj);
                plot(app.UIAxes,app.x,app.myRecording);
                xlim(app.UIAxes,[0 5]);
                ylim(app.UIAxes,[-0.2 0.2]);
                app.myRecording1 = fft(app.myRecording)';
                plot(app.UIAxes4,app.y,fftshift(app.myRecording1));
                xlim(app.UIAxes4,[-8000 8000]);
                ylim(app.UIAxes4,[-70 70]);
                app.Lamp.Color = [0.5 0.5 0.5] ;
            end

        end

        % Value changed function: FilterSwitch
        function FilterSwitchValueChanged(app, event)
            %value = app.FilterSwitch.Value;
            
            app.out1 = app.myRecording1 .* app.h_rec1;
            app.out = fliplr(ifftshift(ifft(app.out1)));
            if strcmp(app.FilterSwitch.Value, 'On')
                plot(app.UIAxes2,app.x,app.out);
                xlim(app.UIAxes2,[0 5]);
                ylim(app.UIAxes2,[-0.2 0.2]);
                app.UIAxes2.Title.String = '滤波后信号（时域）';
                plot(app.UIAxes5,app.y,-fftshift(app.out1));
                xlim(app.UIAxes5,[-8000 8000]);
                ylim(app.UIAxes5,[-70 70]);
                app.UIAxes5.Title.String = '滤波后信号（频域）';
                app.Lamp_2.Color = [0 1 0] ;
            else
                plot(app.UIAxes2,app.x,app.h_rec);
                xlim(app.UIAxes2,[2.49 2.51]);
                ylim(app.UIAxes2,[-0.1 0.5]);
                app.UIAxes2.Title.String = 'Filter(时域）';
                plot(app.UIAxes5,app.y,abs(fftshift(app.h_rec1)));
                xlim(app.UIAxes5,[-8000 8000]);
                ylim(app.UIAxes5,[-0.1 1.1]);
                app.UIAxes5.Title.String = 'Filter(频域）';
                app.Lamp_2.Color = [0.5 0.5 0.5] ;
            end

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            
            if strcmp(app.Switch.Value, 'On')
                sound(abs(app.myRecording),8000)
                app.OriginaudioLamp.Color = [0 1 0] ;
                app.AfteraudioLamp.Color = [0.5 0.5 0.5] ;
            else
                sound(abs(app.out),8000);
                app.AfteraudioLamp.Color = [0 1 0] ;
                app.OriginaudioLamp.Color = [0.5 0.5 0.5] ;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '语音信号（时域）')
            xlabel(app.UIAxes, 'T(s)')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '滤波后信号（时域）')
            xlabel(app.UIAxes2, 'T(s)')
            ylabel(app.UIAxes2, 'Y')
            app.AudioRecorderSwitchLabel = uilabel(app.UIFigure);
            app.AudioRecorderSwitchLabel.Text = 'AudioRecorder';
            app.AudioRecorderSwitch = uiswitch(app.UIFigure, 'slider');
            app.AudioRecorderSwitch.ValueChangedFcn = createCallbackFcn(app, @AudioRecorderSwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, '语音信号（频域）')
            xlabel(app.UIAxes4, 'F(Hz)')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, '滤波后信号（频域）')
            xlabel(app.UIAxes5, 'F(Hz)')
            ylabel(app.UIAxes5, 'Y')
            app.FilterSwitchLabel = uilabel(app.UIFigure);
            app.FilterSwitchLabel.Text = 'Filter';
            app.FilterSwitch = uiswitch(app.UIFigure, 'slider');
            app.FilterSwitch.ValueChangedFcn = createCallbackFcn(app, @FilterSwitchValueChanged, true);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Switch = uiswitch(app.UIFigure, 'rocker');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.OriginaudioLampLabel = uilabel(app.UIFigure);
            app.OriginaudioLampLabel.Text = 'Origin audio';
            app.OriginaudioLamp = uilamp(app.UIFigure);
            app.AfteraudioLampLabel = uilabel(app.UIFigure);
            app.AfteraudioLampLabel.Text = 'After audio';
            app.AfteraudioLamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = audioProcessingApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end